classdef Deep_learning_app_v2_filterFP_perilesion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        StopAnalysisButton              matlab.ui.control.StateButton
        StitcedImagesCheckBox           matlab.ui.control.CheckBox
        filenameStructure               matlab.ui.control.ListBox
        FilenameStructureLabel          matlab.ui.control.Label
        InputSizepxEditFieldLabel_2     matlab.ui.control.Label
        InputSizepxEditField_2          matlab.ui.control.NumericEditField
        InputsizepxEditField            matlab.ui.control.NumericEditField
        InputsizepxEditFieldLabel       matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        SaveFolderLabel                 matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        FolderformatstringEditField     matlab.ui.control.EditField
        FolderformatstringEditFieldLabel  matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        SelectFoldersButton             matlab.ui.control.Button
        SystemLogTextArea               matlab.ui.control.TextArea
        SystemLogLabel                  matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapLabel                    matlab.ui.control.Label
        RGBAugmentationCheckBox         matlab.ui.control.CheckBox
        BrowseButton                    matlab.ui.control.Button
        DeeplearningmodelpathEditField  matlab.ui.control.EditField
        DeeplearningmodelpathLabel      matlab.ui.control.Label
        RunDeeplearningButton           matlab.ui.control.Button
        ProgressAxes                    matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
        AnalysisTab                     matlab.ui.container.Tab
        RemoveFoldersButton             matlab.ui.control.Button
        OverlayonretardanceimageCheckBox  matlab.ui.control.CheckBox
        VisualizemyelindefectsSelectsamplenamebelowButton  matlab.ui.control.Button
        SavematfileCheckBox             matlab.ui.control.CheckBox
        CalculatemyelindamageCheckBox   matlab.ui.control.CheckBox
        CalculateretardanceCheckBox     matlab.ui.control.CheckBox
        RunAnalysisButton               matlab.ui.control.Button
        filenameStructure_2             matlab.ui.control.ListBox
        SampleNamesLabel                matlab.ui.control.Label
        SelectFoldersButton_2           matlab.ui.control.Button
    end

    
    properties (Access = private)
        detector; % handle for the deep learning detector we are using
        all_folders = []; % handles the folders that we will loop through for analysis with deep learning
        save_folder = [];
        inputSize = []; % get input size for network
        sampleNames = [];
        sampleInfo = []; % handle the grid size, mag and overlap for samples for stitching and other func
        filepathsAnalysis = []; % handle the filenames for each sample
        analysisFolder = [];
    end
    
    methods (Access = private)
        function load_Detector(app, filepath)
            warning('off', 'MATLAB:load:unableToReadFile');
            tmp = load(filepath);
            if isfield(tmp, 'training_struct')
                tmp = tmp.training_struct;
                if isfield(tmp, 'detector')
                    app.detector = tmp.detector;
                    updateLog(app, ['Loaded detector: ', filepath]);
                    app.DeeplearningmodelpathEditField.Value = filepath;
                else
                    uialert(app.UIFigure, 'Selected file does not contain a ''detector'' variable.', 'Load Error');
                    updateLog(app, ['Load Error ', filepath]);
                end
            else
                uialert(app.UIFigure, 'Selected file does not contain a ''training_struct'' variable.', 'Load Error');
                updateLog(app, ['Load Error ', filepath]);
            end

            if isfield(tmp, 'inputSize')
                app.inputSize = tmp.inputSize;
                app.InputsizepxEditField.Value = app.inputSize(1);
                app.InputSizepxEditField_2.Value = app.inputSize(2);
            else
                uialert(app.UIFigure, 'Selected file does not contain a ''inputSize'' variable.', 'Load Error');
                updateLog(app, ['Load Error: ', 'Selected file does not contain a ''inputSize'' variable. Manually input network size']);
            end

            % Restore warning state
            warning('on', 'MATLAB:load:unableToReadFile');
        end

        function updateLog(app, message)
            % Get current time
            timestamp = datetime("now");

            % Format log entry
            logEntry = sprintf('[%s] >> %s \n%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%\n', timestamp, message);
          
            % Append to log
            currentLog = app.SystemLogTextArea.Value;
            if ischar(currentLog)
                currentLog = {currentLog}; % Convert to cell if needed
            end
            maxLines = 9; % this is the number of lines in teh current GUI
            combinedLog = [currentLog; logEntry];
            if numel(combinedLog) > maxLines
                combinedLog = combinedLog(end - maxLines + 1:end);  % Keep latest N
            end
            app.SystemLogTextArea.Value = combinedLog;
            % app.SystemLogTextArea.Value = [currentLog; logEntry];  % Add to bottom
            drawnow;
            % app.SystemLogTextArea.Value = app.SystemLogTextArea.Value;  % Force scroll to bottom
        end
        
        function updateProgressBar(app, progress, labelText)
            % progress: value between 0 and 1

            % Clear previous plot
            cla(app.ProgressAxes);

            % Draw a green rectangle representing progress
            x = [0, progress, progress, 0];
            y = [0, 0, 1, 1];
            fill(app.ProgressAxes, x, y, [0 1 0], 'EdgeColor', 'none'); % green

            % Set axes limits and hide ticks
            app.ProgressAxes.XLim = [0 1];
            app.ProgressAxes.YLim = [0 1];
            app.ProgressAxes.XTick = [];
            app.ProgressAxes.YTick = [];
            % Handle title
            if progress == 0
                app.ProgressAxes.Title.String = 'Standby';
                % Optionally update title
            elseif nargin >= 3 && ~isempty(labelText)
                percentStr = sprintf('%.0f%%', progress * 100);
                app.ProgressAxes.Title.String = sprintf('Progress: %s (%s)', labelText, percentStr);
            end
        end

        function name = getSampleName(app, path)
            parts = split(path, '/');  % split by file separator (platform independent)
            % name = [parts{end-2} '_' parts{end-1}];  % character vector
            name = [parts{end}];  % character vector
        end

        function sampleInfo = getSampleInfo(app,path)
            main_path = pwd;
            cd(path)
            fid=fopen('Sample_Notes.txt');
            % set linenum to the desired line number that you want to import
            linenum = 9;
            % use '%s' if you want to read in the entire line or use '%f' if you want to read only the first numeric value
            C = textscan(fid,'%s',1,'delimiter',';', 'headerlines',linenum-1);
            tmp = C{1,1};
            info = split(tmp,':');
            tmp2 = split(info{end},'x');
            sampleInfo.gridx = str2num(tmp2{1});
            sampleInfo.gridy = str2num(tmp2{end});
            % Extract Overlap Value
            linenum_overlap = 10; % Line where overlap is defined
            frewind(fid); % Reset file position indicator
            C = textscan(fid,'%s',1,'delimiter',';', 'headerlines',linenum_overlap-1);
            tmp = C{1,1};
            info = split(tmp,':');
            sampleInfo.overlap = str2double(info{end}); % Convert to numeric value
            cd(main_path)
        end

        function sampleNames = extractSampleNames(app, folderPath)
            % Get all matching files in the folder
            files = dir(fullfile(folderPath, '*_Fused.tif'));

            % Initialize cell array
            sampleNames = {};

            for i = 1:length(files)
                % Extract full filename
                fname = files(i).name;

                % Split at underscore
                parts = split(fname, '_');

                % Extract base sample name (e.g., SampleName)
                sampleName = parts{1};  % adjust if you use more parts like ID_subID

                % Optionally check if related files exist
                fusedPath = fullfile(folderPath, [sampleName, '_Fused.tif']);
                retPath = fullfile(folderPath, [sampleName, '_ret.tif']);
                matPath = fullfile(folderPath, [sampleName, '_annotations.mat']);

                if exist(fusedPath, 'file') && exist(retPath, 'file') && exist(matPath, 'file')
                    sampleNames{end+1} = sampleName; %#ok<AGROW>
                else
                    warning('Missing one or more files for sample: %s', sampleName);
                end
            end

            % Remove duplicates in case multiple matches
            sampleNames = unique(sampleNames);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.SystemLogTextArea.Value = "Starting process...";

            % app/ is one level below the repository root.
            appDir = fileparts(mfilename('fullpath'));
            repoRoot = fileparts(appDir);
            
            % Add runtime code only.
            addpath(genpath(fullfile(repoRoot, 'src')));
            addpath(fullfile(repoRoot, 'qBRM'));
            
            % Load the default YOLO detector from the repository.
            detectorFile = fullfile(repoRoot, 'models', 'detector', 'training_struct_SM080_SM077_128_RGBV2.mat');            
            if ~isfile(detectorFile)
                error('Default detector model not found: %s', detectorFile);
            end
            
            app.load_Detector(detectorFile);
            
            % Keep the current default-save behavior under app/ for now.
            app.save_folder = '';
            app.SaveFolderEditField.Value = '';
        end

        % Button pushed function: RunDeeplearningButton
        function RunDeeplearningButtonPushed(app, event)
            for i =1:length(app.all_folders)
                cd(app.all_folders{i})
                app.updateProgressBar(i/length(app.all_folders), sprintf('Running analysis on sample %s', app.sampleNames{i}));
                pause(0.5)

                % check if the qBRM folder already exists
                if ~isfolder('qBRM')
                    app.updateLog(['Running qBRM analysis: ', pwd]) 
                    solve_widefield_qBRM_folder(pwd);

                else
                    app.updateLog(sprintf('Already ran qBRM analysis on sample %s', app.sampleNames{i})) 
                end

                % get the samplename for saving purposes
                samplename = app.sampleNames(i);

                % load in the overlap and image size for stitching
                app.sampleInfo = app.getSampleInfo(app.all_folders{i});
                app.updateLog(sprintf('Getting sample info (%s)', app.sampleNames{i}))

                % go into qBRM folder
                cd([app.all_folders{i}, '/qBRM/RGB_norm'])

                % loop through all RGB images to run object detection
                % and save to that folder
                images = dir('*.tif');

                app.updateLog(sprintf('Running analysis on sample %s', app.sampleNames{i}))

                counter = 1; % counter for progress bar
               
                for ii =1:length(images)

                    % check if _annotation_128.mat is exit, if so, skip it
                    img_name = images(ii).name;

                    % Construct expected annotation output name
                    annotation_file = fullfile(images(ii).folder, sprintf('%s_annotations_128.mat', img_name));

                    % Skip if annotation already exists
                    if exist(annotation_file, 'file')
                        % app.updateLog(sprintf('Skipping %s: annotation already exists', img_name));
                        continue;
                    end

                    % load RGB image and do data augmentation (test time
                    % augmentation)
                    img = imread(fullfile(images(ii).folder, images(ii).name));

                    % check if the img is all black, if so, skip the count
                    % defects
                    isBlackTile = all(img(:) == 0);
                    if isBlackTile 
                        % Log skipping action
                        app.updateLog(sprintf('Skipping %s: Image is all black', img_name));
                        
                        % Define empty variables matching the 'single' data type from MATLAB
                        all_bboxes  = zeros(0, 4, 'single');
                        all_scores  = zeros(0, 1, 'single');
                        bb_detector = zeros(0, 4, 'single');
                        bb_scores   = zeros(0, 1, 'single');
                        
                        % Save the empty annotation file
                        save(fullfile(images(ii).folder, sprintf('%s_annotations_128.mat', images(ii).name)), 'all_bboxes', 'all_scores', 'bb_detector', 'bb_scores');
                    
                        % Update progress bar counter for the skipped stages
                        counter = counter + 3;
                        app.updateProgressBar(counter / (length(images) * 3), sprintf('Skipped all-black image %s', app.sampleNames{i}));
                                            
                        continue; % Skip to next image
                    end

                    Fused_RGB = img;
                    Fused_BRG = cat(3,Fused_RGB(:,:,3),Fused_RGB(:,:,1),Fused_RGB(:,:,2));
                    Fused_GBR = cat(3,Fused_RGB(:,:,2),Fused_RGB(:,:,3),Fused_RGB(:,:,1));

                    % analysis stage 1 flip RGB
                    app.updateLog(sprintf('Analyzing %s (img #%d/%d) with object detection Network...Stage 1', app.sampleNames{i}, ii, length(images)))
                    [detection_struct_RGB] = detect_myelin_defects(Fused_RGB,app.detector, ceil((app.InputsizepxEditField.Value*(app.OverlapEditField.Value/100)/2)), [app.InputsizepxEditField.Value app.InputsizepxEditField.Value]);
                    pause(0.01) % yeild point in case we wanna cancel
                    if app.StopAnalysisButton.Value
                        app.updateLog("Analysis stopped by user.");
                        app.StopAnalysisButton.Value = 0;
                        return;
                    end
                    app.updateProgressBar(counter/(length(images)*3), sprintf('Running analysis on sample %s (img #%d/%d)', app.sampleNames{i}, ii, length(images)));
                    counter = counter +1;

                    % analysis stage 2 flip RGB
                    app.updateLog(sprintf('Analyzing %s (img #%d/%d) with object detection Network...Stage 2', app.sampleNames{i}, ii, length(images)))
                    [detection_struct_BRG] = detect_myelin_defects(Fused_BRG,app.detector, ceil((app.InputsizepxEditField.Value*(app.OverlapEditField.Value/100)/2)), [app.InputsizepxEditField.Value app.InputsizepxEditField.Value]);
                    pause(0.01) % yeild point in case we wanna cancel
                    if app.StopAnalysisButton.Value
                        app.updateLog("Analysis stopped by user.");
                        app.StopAnalysisButton.Value = 0;
                        return;
                    end
                    app.updateProgressBar(counter/(length(images)*3), sprintf('Running analysis on sample %s (img #%d/%d)', app.sampleNames{i},ii, length(images)));
                    counter = counter+1;

                    % analysis stage 3 flip RGB
                    app.updateLog(sprintf('Analyzing %s (img #%d/%d) with object detection Network...Stage 3', app.sampleNames{i}, ii, length(images)))
                    [detection_struct_GBR] = detect_myelin_defects(Fused_GBR,app.detector, ceil((app.InputsizepxEditField.Value*(app.OverlapEditField.Value/100)/2)), [app.InputsizepxEditField.Value app.InputsizepxEditField.Value]); 
                    pause(0.01) % yeild point in case we wanna cancel
                    if app.StopAnalysisButton.Value
                        app.updateLog("Analysis stopped by user.");
                        app.StopAnalysisButton.Value = 0;
                        return;
                    end
                    app.updateProgressBar(counter/(length(images)*3), sprintf('Running analysis on sample %s (img #%d/%d)', app.sampleNames{i}, ii, length(images)));
                    counter = counter+1;

                    % get all bbox_info and save
                    all_bboxes = vertcat(detection_struct_RGB.bboxes,detection_struct_BRG.bboxes,detection_struct_GBR.bboxes);
                    all_scores = vertcat(detection_struct_RGB.scores,detection_struct_BRG.scores,detection_struct_GBR.scores);
                    [selectedBbox, selectedScore] = selectStrongestBbox(all_bboxes,all_scores, 'OverlapThreshold', 0.5, 'RatioType', 'Min');
                    bb_detector = selectedBbox;
                    bb_scores = selectedScore;
                    save(sprintf('%s_annotations_128.mat', images(ii).name), 'all_bboxes', 'all_scores', 'bb_detector', 'bb_scores')
                end

                app.updateProgressBar(i/length(app.all_folders), sprintf('Stitching annotations and images %s', app.sampleNames{i}));
                pause(0.25)
                
                
                % Check if it's a single tile vs multi-tile
                if app.sampleInfo.gridx == 1 && app.sampleInfo.gridy == 1
                    % Single image: load directly without running the stitching loop
                    out_ret = imread(fullfile(app.all_folders{i}, 'qBRM', 'Ret', 'ret_001.tif'));
                    out_RGB = imread(fullfile(app.all_folders{i}, 'qBRM', 'RGB_norm', 'RGB_norm_001.tif'));
                else
                    % Multi-tile grid: run the stitching function 

                    % Stitch retardance and qBRM image
                    app.updateLog(sprintf('Stitching retardance image %s', app.sampleNames{i}))
                    %cd([app.all_folders{i}, '/qBRM/ret'])
                    out_ret = stitch_qBRM([app.all_folders{i}, '/qBRM/Ret'], app.sampleInfo.gridx,app.sampleInfo.gridy,app.sampleInfo.overlap, 'ret_%03d.tif');
    
                    % stitch qBRM RGB norm image
                    app.updateLog(sprintf('Stitching qBRM image %s', app.sampleNames{i}))
                    %cd([app.all_folders{i}, '/qBRM/RGB_norm'])
                    out_RGB = stitch([app.all_folders{i}, '/qBRM/RGB_norm'], app.sampleInfo.gridx,app.sampleInfo.gridy,app.sampleInfo.overlap, 'RGB_norm_%03d.tif');
                end


                % Now we calculate the correct location of the bboxes (given offset from (0,0) for each image)
                boundingBoxesFixed = [];
                boundingBoxesScoresFixed = [];
                boundingBoxesFixedall = [];
                boundingBoxesScoresFixedall = [];
                gridRows = app.sampleInfo.gridy;
                gridCols = app.sampleInfo.gridx;
                imageWidth = 2960;  % Width of each image
                imageHeight = 2960; % Height of each image
                overlap = app.sampleInfo.overlap/100;     % Overlap as a fraction of image dimensions
                xStep = imageWidth * (1 - overlap);
                yStep = imageHeight * (1 - overlap);
                imageNumbers = zeros(gridRows, gridCols);
                xOffsets = zeros(gridRows, gridCols);
                yOffsets = zeros(gridRows, gridCols);

                % correct bbox information for stitching
                counter_bbox_fix = 1;
                for row = 1:gridRows
                    for col = 1:gridCols
                        % Determine image number in snaking pattern
                        if mod(row, 2) == 1  % Odd row (left-to-right)
                            imageNumber = (row - 1) * gridCols + col;
                        else                 % Even row (right-to-left)
                            imageNumber = row * gridCols - col + 1;
                        end
                        % Store the image number
                        imageNumbers(row, col) = imageNumber;
                        % Calculate x and y offsets
                        xOffsets(row, col) = (col - 1) * xStep;
                        yOffsets(row, col) = (row - 1) * yStep;
                        current_xoff = (col - 1) * xStep;
                        current_yoff = (row - 1) * yStep;
                        boundingBoxes = [];
                        boundingBoxesall = load(sprintf('RGB_norm_%03d.tif_annotations_128.mat',imageNumber)).all_bboxes;
                        boundingBoxesscores = load(sprintf('RGB_norm_%03d.tif_annotations_128.mat',imageNumber)).all_scores;
                        boundingBoxes = load(sprintf('RGB_norm_%03d.tif_annotations_128.mat',imageNumber)).bb_detector;
                        boundingBoxesScores = load(sprintf('RGB_norm_%03d.tif_annotations_128.mat',imageNumber)).bb_scores;
                        % Crop the bounding boxes to the image boundary
                        imageBoundary = [1, 1, 2960, 2960]; % Image size [width, height]
                        [boundingBoxes, cropIdx ]= bboxcrop(boundingBoxes, imageBoundary);
                        boundingBoxesScores = boundingBoxesScores(cropIdx);

                        x = boundingBoxesall(:,1)+current_xoff;
                        y = boundingBoxesall(:,2)+current_yoff;
                        widths = boundingBoxesall(:,3);
                        heights = boundingBoxesall(:,4);
                        adjustedbbox = [x,y,widths,heights];
                        boundingBoxesFixedall = vertcat(boundingBoxesFixedall, adjustedbbox);
                        boundingBoxesScoresFixedall = vertcat(boundingBoxesScoresFixedall, boundingBoxesscores);
                        % do this for just NMS bboxes as well
                        x = boundingBoxes(:,1)+current_xoff;
                        y = boundingBoxes(:,2)+current_yoff;
                        widths = boundingBoxes(:,3);
                        heights = boundingBoxes(:,4);
                        adjustedbbox = [x,y,widths,heights];
                        boundingBoxesFixed = vertcat(boundingBoxesFixed, adjustedbbox);
                        boundingBoxesScoresFixed = vertcat(boundingBoxesScoresFixed, boundingBoxesScores);

                        counter_bbox_fix = counter_bbox_fix + 1;
                        app.updateProgressBar(counter_bbox_fix/(gridRows*gridCols), sprintf('Correcting bbox location %s', app.sampleNames{i}));
                    end
                end
                bb_detector = boundingBoxesFixed;
                bb_scores = boundingBoxesScoresFixed;
                all_scores = boundingBoxesScoresFixedall;
                all_bboxes = boundingBoxesFixedall;
                app.updateProgressBar(0, 'Standby');

                % save corrected bboxes and sitched image into the main save folder
                app.updateLog(sprintf('Saving Stitched images and deep learning annotations for %s', app.sampleNames{i}))
                cd(app.save_folder)

                % Set 4 GB threshold in bytes (4 * 1024^3), downsample the image if larger than 4GB
                % so that RGB and ret will not too big

                    bytes_4GB = 4 * 1024^3;
    
                    size_RGB = whos('out_RGB').bytes;
                    size_ret = whos('out_ret').bytes;
    
                    if size_RGB > bytes_4GB
                        out_RGB_save = imresize(out_RGB, 0.5);
                        imwrite(out_RGB_save, sprintf('%s_downsampled_Fused_RGB.tif',app.sampleNames{i}))
                        % Preserve original variables
                        all_bboxes_original = all_bboxes;
                        bb_detector_original = bb_detector;
                    
                        % Scale to downsampled-image coordinates
                        all_bboxes = all_bboxes * 0.5;
                        bb_detector = bb_detector * 0.5;
                        save(sprintf('%s_downsampled_annotations_128.mat', app.sampleNames{i}), 'all_bboxes', 'all_scores', 'bb_detector', 'bb_scores');
                        % Restore full-resolution coordinates in memory
                        all_bboxes = all_bboxes_original;
                        bb_detector = bb_detector_original;
                    else
                    imwrite(out_RGB, sprintf('%s_Fused_RGB.tif',app.sampleNames{i}))
                    save(sprintf('%s_annotations_128.mat',app.sampleNames{i}),'all_bboxes', 'all_scores', 'bb_detector', 'bb_scores')
                    
                    end
    
                    if size_ret > bytes_4GB
                        out_ret_save = imresize(out_ret, 0.5);
                        save_tiff(im2single(out_ret_save), sprintf('%s_downsampled_retardance.tif',app.sampleNames{i}))
                    else 
                    save_tiff(im2single(out_ret), sprintf('%s_retardance.tif',app.sampleNames{i}))
                    end
              
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % select file for deep learning detector
            [file, path] = uigetfile('*.mat', 'Select Trained Detector File');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Selection Cancelled');
                return;
            end

            fullpath = fullfile(path, file);
            
            % Load the detector 
            load_Detector(app, fullpath)
            
        end

        % Button pushed function: SelectFoldersButton
        function SelectFoldersButtonPushed(app, event)
            folderPath = uigetdir('', 'Select Main Folder');
            if folderPath ~= 0
                app.updateLog(['Selected folder: ', folderPath])
                app.RunDeeplearningButton.Enable = "on";
            else
                app.updateLog('No folder selected')
                app.RunDeeplearningButton.Enable = "off";
                return;
            end
            
            matching_folders = findFoldersWithString(folderPath, app.FolderformatstringEditField.Value);
            app.all_folders = cat(2,matching_folders, app.all_folders);
            app.ListBox.Items = app.all_folders;

            % extract sample names from this file structure
            app.sampleNames = cellfun(@(p) app.getSampleName(p), app.all_folders, 'UniformOutput', false);
            app.filenameStructure.Items = app.sampleNames;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.save_folder = uigetdir('', 'Select Main Folder');
            if app.save_folder ~= 0
                app.updateLog(['Selected save folder: ', app.save_folder])
            else
                app.updateLog('No folder selected')
            end
        end

        % Button pushed function: SelectFoldersButton_2
        function SelectFoldersButton_2Pushed(app, event)
            app.analysisFolder = uigetdir('', 'Select Main Folder');
            if app.analysisFolder ~= 0
                app.updateLog(['Selected folder: ', app.analysisFolder])
                app.RunDeeplearningButton.Enable = "on";
            else
                app.updateLog('No folder selected')
                app.RunDeeplearningButton.Enable = "off";
            end
            
            images = dir([app.analysisFolder, '/*Fused_RGB.tif']);
            ret_img = dir('*retardance.tif');
            
            % get the sample names and populate the box
            for i =1:length(images)
                tmp = split(images(i).name, '_');
                % sampleNames_analysis{i} =  [tmp{1},'_',tmp{2}];
                tmp2 = tmp{1};
                for j=1:(length(tmp)-3)
                    tmp2 = [tmp2,'_',tmp{j+1}];
                end
                sampleNames_analysis{i} = tmp2;
            end
            app.filenameStructure_2.Items = sampleNames_analysis;
            app.filepathsAnalysis = fullfile({images.folder}, {images.name});
            %app.ListBox_2.Items = app.filepathsAnalysis;

        end

        % Callback function
        function RunAnalysisButtonPushed(app, event)
            app.updateLog(['Running analysis: ', folderPath])

            % check if we need or want to draw ROIs for these images

            % check if we want to calculate the Retardance average or the
            % myelin damage for these ROIs


        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed2(app, event)
            cd(app.analysisFolder)
            app.RunAnalysisButton.Enable = "on";
            
            for i = 1:length(app.filenameStructure_2.Items)
                samplename = app.filenameStructure_2.Items{i};
                
                % Construct expected filenames
                fusedFile = fullfile(app.analysisFolder, [samplename, '_Fused_RGB.tif']);
                retFile   = fullfile(app.analysisFolder, [samplename, '_retardance.tif']);
                annotFile = fullfile(app.analysisFolder, [samplename, '_annotations_128.mat']);

               
                % Check files exist
                if ~isfile(fusedFile) || ~isfile(annotFile)
                    warning('Missing file(s) for %s. Skipping.', samplename);
                    continue;
                end
                
                if isfile(retFile)
                    img_ret = imread(retFile);
                end
                
                                
                % Load image and annotation
                img     = imread(fusedFile);
                data    = load(annotFile);
                bb_data = data.bb_detector;

                
                % Run defect analysis
                [defect_per_area, num_defects, area_mm_calc, pos] = count_defects_per_area(img, bb_data, 10);
                
                % Filter bounding boxes within ROI
                bb_filtered = filter_bb_in_roi(bb_data, pos);
                [all_bboxes_filtered, all_scores_filtered] = filter_bb_scores_in_roi(data.bb_detector, pos);
                if isempty(all_scores_filtered)
                    all_scores_filtered = ones(size(all_bboxes_filtered,1),1);
                end
                
                % Mask + mean retardance
                mask = poly2mask(pos(:,1), pos(:,2), size(img,1), size(img,2));
                if isfile(retFile)
                    roi_pixels = img_ret(mask);
                    mean_ret = mean(roi_pixels(:));
                else
                    mean_ret = NaN;
                end
                
                % === Apply classifier to remove false positives ===
                classifier_applied = false;
                try
                    % [filtered_bboxes, filtered_scores, ~, ~, tp_mask] = apply_matlab_classifier(...
                    %     img, bb_filtered, all_scores_filtered, 'matlab_defect_classifier.mat');

                    appDir = fileparts(mfilename('fullpath'));
                    repoRoot = fileparts(appDir);
                    
                    classifierFile = fullfile(repoRoot, 'models', 'classifier', 'matlab_defect_classifier.mat');
                    
                    if ~isfile(classifierFile)
                        error('FP classifier model not found: %s', classifierFile);
                    end
                    
                    [filtered_bboxes, filtered_scores, ~, ~, tp_mask] = ...
                        apply_matlab_classifier(img, bb_filtered, all_scores_filtered, classifierFile);
                    
                    % Create false positive mask
                    bb_FP = double(~tp_mask);
                    
                    % Store TP-filtered results
                    bb_detector_tp = filtered_bboxes;
                    all_bboxes_tp = filtered_bboxes;
                    all_scores_tp = filtered_scores;
                    
                    % Calculate metrics with TP-only detections
                    num_defects_tp = size(filtered_bboxes, 1);
                    defect_per_area_tp = num_defects_tp / area_mm_calc;
                    
                    classifier_applied = true;
                    
                    app.updateLog(sprintf('%s: ROI=%d detections, TP=%d (%.1f%% kept)', ...
                        samplename, num_defects, num_defects_tp, 100*num_defects_tp/max(num_defects,1)));
                    
                catch ME
                    warning('Classifier failed for %s: %s. Saving ROI-filtered data only.', samplename, ME.message);
                    app.updateLog(sprintf('%s: Classifier failed - using ROI filtering only', samplename));
                    
                    % Fall back to ROI-filtered data
                    bb_detector_tp = bb_filtered;
                    all_bboxes_tp = all_bboxes_filtered;
                    all_scores_tp = all_scores_filtered;
                    bb_FP = [];
                    num_defects_tp = num_defects;
                    defect_per_area_tp = defect_per_area;
                end
                
                % === SAVE TP-FILTERED VERSION ===
                bb_detector = bb_detector_tp;  % Use consistent naming for TP version
                all_bboxes = all_bboxes_tp;
                all_scores = all_scores_tp;
                save(sprintf('%s_annotations_TP.mat', samplename), ...
                    'bb_detector', 'all_scores', 'all_bboxes');
                clear bb_detector all_bboxes all_scores
                % === SAVE ROI-FILTERED VERSION (UNFILTERED BY CLASSIFIER) ===
                bb_detector = bb_filtered;
                all_bboxes = all_bboxes_filtered;
                all_scores = all_scores_filtered;
                save(sprintf('%s_annotations_ROI_filtered.mat', samplename), ...
                    'bb_detector', 'all_scores', 'all_bboxes','bb_FP');


                % Save .mat file if checkbox is on
                if app.SavematfileCheckBox.Value
                    % Store both ROI and TP metrics
                    defect_struct.defect_per_area_roi = defect_per_area;
                    defect_struct.defect_per_area_tp = defect_per_area_tp;
                    defect_struct.area_mm_calc = area_mm_calc;
                    defect_struct.num_defects_roi = num_defects;
                    defect_struct.num_defects_tp = num_defects_tp;
                    defect_struct.ROI = pos;
                    defect_struct.mean_ret = mean_ret;
                    defect_struct.classifier_applied = classifier_applied;
                    
                    if classifier_applied && ~isempty(bb_FP)
                        defect_struct.bb_FP = bb_FP;
                        defect_struct.num_FP = sum(bb_FP);
                        defect_struct.FP_rate = sum(bb_FP) / max(num_defects, 1);
                    end
                    
                    save(sprintf('defects_data_%s.mat', samplename), 'defect_struct');
                end
        
            end
            app.updateLog('Analysis complete. Both ROI-filtered and TP-filtered results saved.')

        end

        % Button pushed function: 
        % VisualizemyelindefectsSelectsamplenamebelowButton
        function VisualizemyelindefectsSelectsamplenamebelowButtonPushed(app, event)
            if isempty(app.filenameStructure_2.Value)
                uialert(app.UIFigure, 'Please select a sample from the list.', 'No Selection');
                return;
            end

            samplename = app.filenameStructure_2.Value;  % e.g., 'AM339_S7C3'
            
            
            if app.OverlayonretardanceimageCheckBox.Value
                img = imread([app.analysisFolder, '/', samplename, '_retardance.tif']);
            else
                img = imread([app.analysisFolder, '/', samplename, '_Fused_RGB.tif']);
            end
            
            app.updateLog(['Loading annotations and stitched image for ', samplename])
            % load in annotations 
            data = load([app.analysisFolder, '/', samplename, '_annotations_128.mat']);
            bboxes = data.bb_detector;
            
            % 1. Determine Downsampling Scale & Resize Image
            info = whos('img');
            img_size_gb = info.bytes / (1024^3);

            if img_size_gb > 1.5
                scale = 0.5; % Downsample factor (e.g., 50% of original size)
                img_preview = imresize(img, scale);
                app.updateLog(['Loading downsampled stitched image for ', samplename])
            else
                scale = 1;
                img_preview = img;
            end

            % 2. Scale the bounding box coordinates to match the preview image
            % (Subtracting 0.5 converts to spatial coordinates, scales, then re-centers)
            bboxes_ds = (bboxes - 0.5) * scale + 0.5;

            % 3. Calculate 4-Tile Non-Overlapping Split (2x2 Grid)
            [h, w, ~] = size(img_preview);

            mid_y = floor(h / 2);
            mid_x = floor(w / 2);

            % Define Pixel Ranges for 4 Tiles
            % Tile 1: Top-Left  | Tile 2: Top-Right
            % Tile 3: Bottom-Left | Tile 4: Bottom-Right
            r_idx = {1:mid_y, 1:mid_y, (mid_y+1):h, (mid_y+1):h};
            c_idx = {1:mid_x, (mid_x+1):w, 1:mid_x, (mid_x+1):w};

            % Precompute bounding box centers
            x_centers_ds = bboxes_ds(:, 1) + bboxes_ds(:, 3)/2;
            y_centers_ds = bboxes_ds(:, 2) + bboxes_ds(:, 4)/2;

            % 4. Crop Images & Filter/Adjust Bounding Boxes
            tiles = cell(1, 4);
            bboxes_tiles = cell(1, 4);

            for k = 1:4
                % Crop tile image
                tiles{k} = img_preview(r_idx{k}, c_idx{k}, :);

                % Tile boundaries
                x_min = c_idx{k}(1); x_max = c_idx{k}(end);
                y_min = r_idx{k}(1); y_max = r_idx{k}(end);

                % Filter boxes whose center lies inside this tile
                in_tile = (x_centers_ds >= x_min & x_centers_ds <= x_max) & ...
                          (y_centers_ds >= y_min & y_centers_ds <= y_max);

                b_temp = bboxes_ds(in_tile, :);

                % Shift box origin to match the tile's cropped origin
                if ~isempty(b_temp)
                    b_temp(:, 1) = b_temp(:, 1) - (x_min - 1);
                    b_temp(:, 2) = b_temp(:, 2) - (y_min - 1);
                end

                bboxes_tiles{k} = b_temp;
            end

            %% Show images and overlay bbox annotations for all 4 tiles
            tile_names = {'Top-Left', 'Top-Right', 'Bottom-Left', 'Bottom-Right'};
            % 
            % for k = 1:4
            %     figure;
            %     imshow(tiles{k});
            %     hold on;
            %     if ~isempty(bboxes_tiles{k})
            %         [patchx, patchy] = create_rect_patches_from_bbox(bboxes_tiles{k});
            %         patch(patchx, patchy, 'red', 'EdgeColor', 'w', 'Facecolor', 'none', 'LineWidth', 2);
            %     end
            %     title(['Tile ', num2str(k), ' (', tile_names{k}, ')']);
            % end

            figure('Name', 'Tile Preview', 'NumberTitle', 'off');
            for k = 1:4
                subplot(2, 2, k);
                if ~isempty(bboxes_tiles{k})
                    img_disp = insertShape(tiles{k}, 'Rectangle', bboxes_tiles{k}, 'Color', 'white', 'LineWidth', 2);
                else
                    img_disp = tiles{k};
                end
                imshow(img_disp);
                title(tile_names{k});
            end

            % % show image then overlay bbox annotations 
            % fig1 = figure;
            % imshow(img)
            % 
            % % visualize defects
            % [patchx, patchy] = create_rect_patches_from_bbox(bboxes_preview);
            % patch_handle1 = patch(patchx,patchy,'red','EdgeColor','w','Facecolor','none','LineWidth', 2);
        end

        % Button pushed function: RemoveFoldersButton
        function RemoveFoldersButtonPushed(app, event)
            % Get selected items (string array)
            selectedItems = app.filenameStructure_2.Value;
            
            % Check if anything is selected
            if isempty(selectedItems)
                app.updateLog('No items selected to remove');
                uialert(app.UIFigure, 'Please select items to remove', 'No Selection');
                return;
            end
            
            % Get current items
            currentItems = app.filenameStructure_2.Items;
            
            % Log which items are being removed
            for i = 1:length(selectedItems)
                app.updateLog(['Removing: ', char(selectedItems(i))]); % Convert to char
            end
            
            % Find which items to keep (inverse of selection)
            keepMask = ~ismember(currentItems, selectedItems);
            
            % Update both arrays
            app.filenameStructure_2.Items = currentItems(keepMask);
            app.filepathsAnalysis = app.filepathsAnalysis(keepMask);
            
            % Clear selection
            app.filenameStructure_2.Value = {};
            
            % Check if any items remain
            if sum(keepMask) == 0
                app.updateLog('All folders removed');
                app.RunDeeplearningButton.Enable = "off";
                app.analysisFolder = 0;
            else
                app.updateLog(['Removed ', num2str(length(selectedItems)), ' folder(s). ', ...
                              num2str(sum(keepMask)), ' remaining.']);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ProgressAxes = uiaxes(app.MainTab);
            title(app.ProgressAxes, 'Progress bar')
            app.ProgressAxes.XTickLabel = '';
            app.ProgressAxes.YTickLabel = '';
            app.RunDeeplearningButton = uibutton(app.MainTab, 'push');
            app.RunDeeplearningButton.ButtonPushedFcn = createCallbackFcn(app, @RunDeeplearningButtonPushed, true);
            app.RunDeeplearningButton.Text = 'Run Deep learning';
            app.DeeplearningmodelpathLabel = uilabel(app.MainTab);
            app.DeeplearningmodelpathLabel.Text = 'Deep learning model path:';
            app.DeeplearningmodelpathEditField = uieditfield(app.MainTab, 'text');
            app.BrowseButton = uibutton(app.MainTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RGBAugmentationCheckBox = uicheckbox(app.MainTab);
            app.RGBAugmentationCheckBox.Text = 'RGB Augmentation';
            app.RGBAugmentationCheckBox.Value = true;
            app.OverlapLabel = uilabel(app.MainTab);
            app.OverlapLabel.Text = 'Overlap (%)';
            app.OverlapEditField = uieditfield(app.MainTab, 'numeric');
            app.OverlapEditField.Value = 50;
            app.SystemLogLabel = uilabel(app.MainTab);
            app.SystemLogLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.MainTab);
            app.SelectFoldersButton = uibutton(app.MainTab, 'push');
            app.SelectFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldersButtonPushed, true);
            app.SelectFoldersButton.Text = 'Select Folders';
            app.ListBox = uilistbox(app.MainTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.FolderformatstringEditFieldLabel = uilabel(app.MainTab);
            app.FolderformatstringEditFieldLabel.Text = 'Folder format string ';
            app.FolderformatstringEditField = uieditfield(app.MainTab, 'text');
            app.FolderformatstringEditField.Value = '_qBRM_';
            app.BrowseButton_2 = uibutton(app.MainTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SaveFolderLabel = uilabel(app.MainTab);
            app.SaveFolderLabel.Text = 'Save Folder:';
            app.SaveFolderEditField = uieditfield(app.MainTab, 'text');
            app.InputsizepxEditFieldLabel = uilabel(app.MainTab);
            app.InputsizepxEditFieldLabel.Text = 'Input size (px)';
            app.InputsizepxEditField = uieditfield(app.MainTab, 'numeric');
            app.InputSizepxEditField_2 = uieditfield(app.MainTab, 'numeric');
            app.InputSizepxEditFieldLabel_2 = uilabel(app.MainTab);
            app.InputSizepxEditFieldLabel_2.Text = 'x';
            app.FilenameStructureLabel = uilabel(app.MainTab);
            app.FilenameStructureLabel.Text = 'Filename Structure:';
            app.filenameStructure = uilistbox(app.MainTab);
            app.filenameStructure.Items = {};
            app.filenameStructure.Value = {};
            app.StitcedImagesCheckBox = uicheckbox(app.MainTab);
            app.StitcedImagesCheckBox.Text = 'Stitced Images ';
            app.StopAnalysisButton = uibutton(app.MainTab, 'state');
            app.StopAnalysisButton.Text = 'Stop Analysis';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.SelectFoldersButton_2 = uibutton(app.AnalysisTab, 'push');
            app.SelectFoldersButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldersButton_2Pushed, true);
            app.SelectFoldersButton_2.Text = 'Select Folders';
            app.SampleNamesLabel = uilabel(app.AnalysisTab);
            app.SampleNamesLabel.Text = 'Sample Names:';
            app.filenameStructure_2 = uilistbox(app.AnalysisTab);
            app.filenameStructure_2.Items = {};
            app.filenameStructure_2.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed2, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.CalculateretardanceCheckBox = uicheckbox(app.AnalysisTab);
            app.CalculateretardanceCheckBox.Text = 'Calculate retardance';
            app.CalculateretardanceCheckBox.Value = true;
            app.CalculatemyelindamageCheckBox = uicheckbox(app.AnalysisTab);
            app.CalculatemyelindamageCheckBox.Text = 'Calculate myelin damage';
            app.CalculatemyelindamageCheckBox.Value = true;
            app.SavematfileCheckBox = uicheckbox(app.AnalysisTab);
            app.SavematfileCheckBox.Text = 'Save .mat file';
            app.SavematfileCheckBox.Value = true;
            app.VisualizemyelindefectsSelectsamplenamebelowButton = uibutton(app.AnalysisTab, 'push');
            app.VisualizemyelindefectsSelectsamplenamebelowButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizemyelindefectsSelectsamplenamebelowButtonPushed, true);
            app.VisualizemyelindefectsSelectsamplenamebelowButton.Text = {'Visualize myelin defects '; '(Select sample name below)'};
            app.OverlayonretardanceimageCheckBox = uicheckbox(app.AnalysisTab);
            app.OverlayonretardanceimageCheckBox.Text = 'Overlay on retardance image';
            app.RemoveFoldersButton = uibutton(app.AnalysisTab, 'push');
            app.RemoveFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFoldersButtonPushed, true);
            app.RemoveFoldersButton.Text = 'Remove Folders';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deep_learning_app_v2_filterFP_perilesion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end